classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        IncrementSizeEditField       matlab.ui.control.NumericEditField
        IncrementSizeEditFieldLabel  matlab.ui.control.Label
        ZButton                      matlab.ui.control.Button
        ZButton_2                    matlab.ui.control.Button
        XButton_2                    matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        YButton_2                    matlab.ui.control.Button
        YButton                      matlab.ui.control.Button
        ZCoordinateEditField_2       matlab.ui.control.NumericEditField
        ZCoordinateEditField_2Label  matlab.ui.control.Label
        YCoordinateEditField_2       matlab.ui.control.NumericEditField
        YCoordinateEditField_2Label  matlab.ui.control.Label
        XCoordinateEditField_4       matlab.ui.control.NumericEditField
        XCoordinateEditField_4Label  matlab.ui.control.Label
        MoveXYZButton_2              matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        EStopButton                  matlab.ui.control.Button
        RobotModelButtonGroup        matlab.ui.container.ButtonGroup
        ABBButton                    matlab.ui.control.ToggleButton
        LinearUR3Button              matlab.ui.control.ToggleButton
        MoveJointButton              matlab.ui.control.Button
        Q7EditField                  matlab.ui.control.NumericEditField
        Q7EditFieldLabel             matlab.ui.control.Label
        Q6EditField                  matlab.ui.control.NumericEditField
        Q6EditFieldLabel             matlab.ui.control.Label
        Q5EditField                  matlab.ui.control.NumericEditField
        Q5EditFieldLabel             matlab.ui.control.Label
        Q3EditField                  matlab.ui.control.NumericEditField
        Q3EditFieldLabel             matlab.ui.control.Label
        Q4EditField                  matlab.ui.control.NumericEditField
        Q4EditFieldLabel             matlab.ui.control.Label
        Q2EditField                  matlab.ui.control.NumericEditField
        Q2EditFieldLabel             matlab.ui.control.Label
        Q1EditField                  matlab.ui.control.NumericEditField
        Q1EditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        eStopController  % Instance of EStopController
        UR3MovementInst  % Instance of UR3Movement
        ABBMovementInst  % Instance of ABBMovement
    end
    
    methods (Access = public)
        function initializeAppWithInstances(app, UR3MovementInstance, ABBMovementInstance, EStopControllerInstance)
             % Assign the instances to the app properties
            app.UR3MovementInst = UR3MovementInstance;
            app.ABBMovementInst = ABBMovementInstance;
            app.eStopController = EStopControllerInstance;
        end
    end
    
    methods (Access = private)
        function moveEndEffector(app, direction)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeField.Value;
            
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                % UR3 is selected
                robotInstance = app.UR3MovementInst;
                robotModel = robotInstance.UR3;
            elseif app.ABBButton.Value
                % ABB is selected
                robotInstance = app.ABBMovementInst;
                robotModel = robotInstance.robot;  % Assuming robot is the property name for ABB instance
            else
                return;  % No robot selected, do nothing
            end
    
            % Get the current end-effector pose using forward kinematics
            currentPose = robotModel.model.fkine(robotModel.model.getpos());
    
            % Determine the translation based on the direction
            switch direction
                case 'x+'
                    translation = [incrementSize, 0, 0];
                case 'x-'
                    translation = [-incrementSize, 0, 0];
                case 'y+'
                    translation = [0, incrementSize, 0];
                case 'y-'
                    translation = [0, -incrementSize, 0];
                case 'z+'
                    translation = [0, 0, incrementSize];
                case 'z-'
                    translation = [0, 0, -incrementSize];
                otherwise
                    return;  % Invalid direction
            end
    
            % Apply the translation to the current position
            newPose = currentPose * transl(translation);
    
            % Use inverse kinematics to find the new joint angles
            newJointAngles = robotModel.model.ikcon(newPose);
    
            % Move the robot to the new joint configuration
            robotInstance.MoveToJointConfiguration(newJointAngles);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EStopButton
        function EStopButtonToggled(app, event)
            if ~app.eStopController.eStopEngaged
                % Engage the emergency stop on both robots
                app.eStopController.EngageEStop();
                app.EStopButton.Text = 'Resume';
            else
                % Disengage the emergency stop on both robots
                app.eStopController.DisengageEStop();
                app.EStopButton.Text = 'EStop';
            end
        end

        % Button pushed function: MoveJointButton
        function MoveJointButtonPushed(app, event)
                % Collect the user inputs for joint angles
                jointAngles = [
                    app.Q1EditField.Value;
                    app.Q2EditField.Value;
                    app.Q3EditField.Value;
                    app.Q4EditField.Value;
                    app.Q5EditField.Value;
                    app.Q6EditField.Value;
                    app.Q7EditField.Value;
                ];
        
                % Check which robot is selected
                if app.LinearUR3Button.Value
                    % Move the UR3 robot using the collected joint angles
                    app.UR3MovementInst.MoveToJointConfiguration(jointAngles');
                elseif app.ABBButton.Value
                    % Move the ABB robot using the collected joint angles
                    app.ABBMovementInst.MoveToJointConfiguration(jointAngles');
                else
                    % If no valid robot is selected, display an error message
                    uialert(app.UIFigure, 'Please select a valid robot model.', 'Robot Selection Error');
                end
           
        end

        % Button pushed function: ZButton
        function ZPosButtonPushed(app, event)
             app.moveEndEffector('z+');
        end

        % Button pushed function: ZButton_2
        function ZNegButtonPushed(app, event)
             app.moveEndEffector('z-');
        end

        % Button pushed function: YButton
        function YNegButtonPushed(app, event)
             app.moveEndEffector('y-');
        end

        % Button pushed function: YButton_2
        function YPosButtonPushed(app, event)
             app.moveEndEffector('y+');
        end

        % Button pushed function: XButton_2
        function XPosButtonPushed(app, event)
             app.moveEndEffector('x+');
        end

        % Button pushed function: XButton
        function XNegButtonPushed(app, event)
             app.moveEndEffector('x-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Q1EditFieldLabel = uilabel(app.Panel);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.Panel, 'numeric');
            app.Q2EditFieldLabel = uilabel(app.Panel);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.Panel, 'numeric');
            app.Q4EditFieldLabel = uilabel(app.Panel);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.Panel, 'numeric');
            app.Q3EditFieldLabel = uilabel(app.Panel);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.Panel, 'numeric');
            app.Q5EditFieldLabel = uilabel(app.Panel);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.Panel, 'numeric');
            app.Q6EditFieldLabel = uilabel(app.Panel);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.Panel, 'numeric');
            app.Q7EditFieldLabel = uilabel(app.Panel);
            app.Q7EditFieldLabel.Text = 'Q7';
            app.Q7EditField = uieditfield(app.Panel, 'numeric');
            app.MoveJointButton = uibutton(app.Panel, 'push');
            app.MoveJointButton.ButtonPushedFcn = createCallbackFcn(app, @MoveJointButtonPushed, true);
            app.MoveJointButton.Text = 'MoveJoint';
            app.RobotModelButtonGroup = uibuttongroup(app.Panel);
            app.RobotModelButtonGroup.Title = 'Robot Model';
            app.LinearUR3Button = uitogglebutton(app.RobotModelButtonGroup);
            app.LinearUR3Button.Text = 'LinearUR3';
            app.LinearUR3Button.Value = true;
            app.ABBButton = uitogglebutton(app.RobotModelButtonGroup);
            app.ABBButton.Text = 'ABB';
            app.EStopButton = uibutton(app.Panel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonToggled, true);
            app.EStopButton.Text = 'EStop';
            app.MoveXYZButton_2 = uibutton(app.UIFigure, 'push');
            app.MoveXYZButton_2.Text = 'MoveXYZ';
            app.XCoordinateEditField_4Label = uilabel(app.UIFigure);
            app.XCoordinateEditField_4Label.Text = 'X Coordinate';
            app.XCoordinateEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditField_2Label = uilabel(app.UIFigure);
            app.YCoordinateEditField_2Label.Text = 'Y Coordinate';
            app.YCoordinateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditField_2Label = uilabel(app.UIFigure);
            app.ZCoordinateEditField_2Label.Text = 'Z Coordinate';
            app.ZCoordinateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YNegButtonPushed, true);
            app.YButton.Text = '-Y';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YPosButtonPushed, true);
            app.YButton_2.Text = '+Y';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XNegButtonPushed, true);
            app.XButton.Text = '-X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XPosButtonPushed, true);
            app.XButton_2.Text = '+X';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZNegButtonPushed, true);
            app.ZButton_2.Text = '-Z';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZPosButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.IncrementSizeEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementSizeEditFieldLabel.Text = 'IncrementSize';
            app.IncrementSizeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end